classdef ODE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        ModelLabel                  matlab.ui.control.Label
        ModelDropDown               matlab.ui.control.DropDown
        TypeLabel                   matlab.ui.control.Label
        TypeSwitch                  matlab.ui.control.Switch
        EditFieldLabel              matlab.ui.control.Label
        FrequencyText               matlab.ui.control.NumericEditField
        AmplitudeSpinnerLabel       matlab.ui.control.Label
        AmplitudeSpinner            matlab.ui.control.Spinner
        DampingSwitchLabel          matlab.ui.control.Label
        DampingSwitch               matlab.ui.control.ToggleSwitch
        InitialPhaseLabel           matlab.ui.control.Label
        InitialPhaseKnob            matlab.ui.control.Knob
        Label                       matlab.ui.control.Label
        GammaText                   matlab.ui.control.Slider
        TypeSwitch_2Label           matlab.ui.control.Label
        TypeSwitch_2                matlab.ui.control.Switch
        PoSpinnerLabel              matlab.ui.control.Label
        PopulationSpinner           matlab.ui.control.Spinner
        InputMethodSwitchLabel      matlab.ui.control.Label
        InputMethodSwitch           matlab.ui.control.RockerSwitch
        KEditFieldLabel             matlab.ui.control.Label
        KEditField                  matlab.ui.control.NumericEditField
        MEditFieldLabel             matlab.ui.control.Label
        MEditField                  matlab.ui.control.NumericEditField
        InitialBirthRate0EditFieldLabel  matlab.ui.control.Label
        InitialBirthRate0EditField  matlab.ui.control.NumericEditField
        BirthRate1EditFieldLabel    matlab.ui.control.Label
        BirthRate1EditField         matlab.ui.control.NumericEditField
        DeathRate0EditFieldLabel    matlab.ui.control.Label
        DeathRate0EditField         matlab.ui.control.NumericEditField
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function HarmonicModel(app)
            A = app.AmplitudeSpinner.Value;
            w = app.FrequencyText.Value;
            phi = app.InitialPhaseKnob.Value;
            T = 2*pi/w;
            gamma = app.GammaText.Value;
            if strcmp(app.TypeSwitch.Value,'Undamped')
                t = 0:T/100:T;
                y = A * cos(w*t + phi);
                app.UIAxes.Title.String = 'Undamped Harmonic Motion';
                app.UIAxes.XLabel.String = 'Time';
                app.UIAxes.YLabel.String = 'Apmlitude';
                plot(app.UIAxes,t,y);
            elseif strcmp(app.TypeSwitch.Value,'Damped')
                    if strcmp(app.DampingSwitch.Value,'Low')
                        w1 = sqrt((w*w)-((gamma*gamma)/4));
                        t = 0:T/99999:10*T;
                        y = A * exp((-(gamma/2))*t) .* cos((w1 * t) + phi);
                        app.GammaText.Limits = [0 w];
                        app.UIAxes.Title.String = 'Light Damped Harmonic Motion';
                        app.UIAxes.XLabel.String = 'Time';
                        app.UIAxes.YLabel.String = 'Apmlitude';
                        plot(app.UIAxes,t,y);
                    elseif strcmp(app.DampingSwitch.Value,'High')
                        w1 = sqrt(((gamma*gamma)/4)-(w*w));
                        a1 = abs(-gamma/2 + w1);
                        a2 = abs(-gamma/2 - w1);
                        t = 0:T/99999:10*T;
                        y = A * (exp((-a1)*t) + exp((-a2)*t));
                        app.GammaText.Limits = [w 10*w];
                        app.UIAxes.Title.String = 'Heavy Damped Harmonic Motion';
                        app.UIAxes.XLabel.String = 'Time';
                        app.UIAxes.YLabel.String = 'Apmlitude';
                        plot(app.UIAxes,t,y);
                    end
            end
        end
        
        function PopulationModel(app)
            P = app.PopulationSpinner.Value;
            if strcmp(app.TypeSwitch_2.Value,'Exponential')
                K = app.KEditField.Value;
                t = 0:1:200;
                p = P * exp(K*t);
                app.UIAxes.Title.String = 'Population Model (Exponential)';
                app.UIAxes.XLabel.String = 'Time';
                app.UIAxes.YLabel.String = 'Population';
                plot(app.UIAxes,t,p);
            elseif strcmp(app.TypeSwitch_2.Value,'Logistic')
                if strcmp(app.InputMethodSwitch.Value,'Rates')
                    b0 = app.InitialBirthRate0EditField.Value;
                    b1 = app.BirthRate1EditField.Value;
                    d0 = app.DeathRate0EditField.Value;
                    K = b1;
                    M = (b0 - d0)/b1;
                elseif strcmp(app.InputMethodSwitch.Value,'Constants')
                    K = app.KEditField.Value;
                    M = app.MEditField.Value;
                end
                t = 0:1/99999:200;
                p = ((M*P)./(P+((M-P)*exp(-1*K*M*t))));
                app.UIAxes.Title.String = 'Population Model (Logistic)';
                app.UIAxes.XLabel.String = 'Time';
                app.UIAxes.YLabel.String = 'Population';
                plot(app.UIAxes,t,p);
            end    
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            value = app.ModelDropDown.Value;
            if strcmp(value,'Population Model')
                set(app.TypeSwitch, 'Visible', 'off');
                set(app.TypeLabel, 'Visible', 'off');
                set(app.FrequencyText, 'Visible', 'off');
                set(app.EditFieldLabel, 'Visible', 'off');
                set(app.AmplitudeSpinner, 'Visible', 'off');
                set(app.AmplitudeSpinnerLabel, 'Visible', 'off');
                set(app.DampingSwitch, 'Visible', 'off');
                set(app.DampingSwitchLabel, 'Visible', 'off');
                set(app.InitialPhaseKnob, 'Visible', 'off');
                set(app.InitialPhaseLabel, 'Visible', 'off');
                set(app.GammaText, 'Visible', 'off');
                set(app.Label, 'Visible', 'off');
                set(app.TypeSwitch_2, 'Visible', 'on');
                set(app.TypeSwitch_2Label, 'Visible', 'on');
                set(app.PopulationSpinner, 'Visible', 'on');
                set(app.PoSpinnerLabel, 'Visible', 'on');
                set(app.KEditField, 'Visible', 'on');
                set(app.KEditFieldLabel, 'Visible', 'on');
            elseif strcmp(value,'Harmonic Model')
                set(app.TypeSwitch, 'Visible', 'on');
                set(app.TypeLabel, 'Visible', 'on');
                set(app.FrequencyText, 'Visible', 'on');
                set(app.EditFieldLabel, 'Visible', 'on');
                set(app.AmplitudeSpinner, 'Visible', 'on');
                set(app.AmplitudeSpinnerLabel, 'Visible', 'on');
                set(app.InitialPhaseKnob, 'Visible', 'on');
                set(app.InitialPhaseLabel, 'Visible', 'on');
                set(app.TypeSwitch_2, 'Visible', 'off');
                set(app.TypeSwitch_2Label, 'Visible', 'off');
                set(app.PopulationSpinner, 'Visible', 'off');
                set(app.PoSpinnerLabel, 'Visible', 'off');
                set(app.InputMethodSwitch, 'Visible', 'off');
                set(app.InputMethodSwitchLabel, 'Visible', 'off');
                set(app.KEditField, 'Visible', 'off');
                set(app.KEditFieldLabel, 'Visible', 'off');
                set(app.MEditField, 'Visible', 'off');
                set(app.MEditFieldLabel, 'Visible', 'off');
                set(app.InitialBirthRate0EditField, 'Visible', 'off');
                set(app.InitialBirthRate0EditFieldLabel, 'Visible', 'off');
                set(app.BirthRate1EditField, 'Visible', 'off');
                set(app.BirthRate1EditFieldLabel, 'Visible', 'off');
                set(app.DeathRate0EditField, 'Visible', 'off');
                set(app.DeathRate0EditFieldLabel, 'Visible', 'off');
                
            end
        end

        % Value changed function: TypeSwitch
        function TypeSwitchValueChanged(app, event)
            value = app.TypeSwitch.Value;
            if strcmp(value,'Undamped')
                set(app.Label, 'Visible', 'off');
                set(app.GammaText, 'Visible', 'off');
                set(app.DampingSwitch, 'Visible', 'off');
                set(app.DampingSwitchLabel, 'Visible', 'off');
            elseif strcmp(value,'Damped')
                set(app.Label, 'Visible', 'on');
                set(app.GammaText, 'Visible', 'on');
                set(app.DampingSwitch, 'Visible', 'on');
                set(app.DampingSwitchLabel, 'Visible', 'on');
            end
        end

        % Value changed function: FrequencyText
        function FrequencyTextValueChanged(app, event)
            HarmonicModel(app);
        end

        % Value changed function: AmplitudeSpinner
        function AmplitudeSpinnerValueChanged(app, event)
            HarmonicModel(app);
        end

        % Value changed function: InitialPhaseKnob
        function InitialPhaseKnobValueChanged(app, event)
            HarmonicModel(app);
        end

        % Value changed function: GammaText
        function GammaTextValueChanged(app, event)
            HarmonicModel(app);
        end

        % Value changed function: DampingSwitch
        function DampingSwitchValueChanged(app, event)
            HarmonicModel(app);
        end

        % Value changed function: TypeSwitch_2
        function TypeSwitch_2ValueChanged(app, event)
            value = app.TypeSwitch_2.Value;
            if strcmp(value,'Logistic')
                set(app.MEditField, 'Visible', 'on');
                set(app.MEditFieldLabel, 'Visible', 'on');
                set(app.InitialBirthRate0EditField, 'Visible', 'off');
                set(app.InitialBirthRate0EditFieldLabel, 'Visible', 'off');
                set(app.BirthRate1EditField, 'Visible', 'off');
                set(app.BirthRate1EditFieldLabel, 'Visible', 'off');
                set(app.DeathRate0EditField, 'Visible', 'off');
                set(app.DeathRate0EditFieldLabel, 'Visible', 'off');
                set(app.InputMethodSwitch, 'Visible', 'on');
                set(app.InputMethodSwitchLabel, 'Visible', 'on');
            elseif strcmp(value,'Exponential')
                set(app.MEditField, 'Visible', 'off');
                set(app.MEditFieldLabel, 'Visible', 'off');
                set(app.InitialBirthRate0EditField, 'Visible', 'off');
                set(app.InitialBirthRate0EditFieldLabel, 'Visible', 'off');
                set(app.BirthRate1EditField, 'Visible', 'off');
                set(app.BirthRate1EditFieldLabel, 'Visible', 'off');
                set(app.DeathRate0EditField, 'Visible', 'off');
                set(app.DeathRate0EditFieldLabel, 'Visible', 'off');
                set(app.InputMethodSwitch, 'Visible', 'off');
                set(app.InputMethodSwitchLabel, 'Visible', 'off');
            end
        end

        % Value changed function: InputMethodSwitch
        function InputMethodSwitchValueChanged(app, event)
            value = app.InputMethodSwitch.Value;
            if strcmp(value,'Rates')
                set(app.KEditField, 'Visible', 'off');
                set(app.KEditFieldLabel, 'Visible', 'off');
                set(app.MEditField, 'Visible', 'off');
                set(app.MEditFieldLabel, 'Visible', 'off');
                set(app.InitialBirthRate0EditField, 'Visible', 'on');
                set(app.InitialBirthRate0EditFieldLabel, 'Visible', 'on');
                set(app.BirthRate1EditField, 'Visible', 'on');
                set(app.BirthRate1EditFieldLabel, 'Visible', 'on');
                set(app.DeathRate0EditField, 'Visible', 'on');
                set(app.DeathRate0EditFieldLabel, 'Visible', 'on');
            elseif strcmp(value,'Constants')
                set(app.KEditField, 'Visible', 'on');
                set(app.KEditFieldLabel, 'Visible', 'on');
                set(app.MEditField, 'Visible', 'on');
                set(app.MEditFieldLabel, 'Visible', 'on');
                set(app.InitialBirthRate0EditField, 'Visible', 'off');
                set(app.InitialBirthRate0EditFieldLabel, 'Visible', 'off');
                set(app.BirthRate1EditField, 'Visible', 'off');
                set(app.BirthRate1EditFieldLabel, 'Visible', 'off');
                set(app.DeathRate0EditField, 'Visible', 'off');
                set(app.DeathRate0EditFieldLabel, 'Visible', 'off');
            end
        end

        % Value changed function: PopulationSpinner
        function PopulationSpinnerValueChanged(app, event)
            PopulationModel(app);
        end

        % Value changed function: KEditField
        function KEditFieldValueChanged(app, event)
            PopulationModel(app);
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
            PopulationModel(app);
        end

        % Value changed function: InitialBirthRate0EditField
        function InitialBirthRate0EditFieldValueChanged(app, event)
            PopulationModel(app);
        end

        % Value changed function: BirthRate1EditField
        function BirthRate1EditFieldValueChanged(app, event)
            PopulationModel(app);
        end

        % Value changed function: DeathRate0EditField
        function DeathRate0EditFieldValueChanged(app, event)
            PopulationModel(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {415, 415};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {281, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModelLabel = uilabel(app.LeftPanel);
            app.ModelLabel.Text = 'Model: ';
            app.ModelDropDown = uidropdown(app.LeftPanel);
            app.ModelDropDown.Items = {'Harmonic Model', 'Population Model'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'Harmonic Model';
            app.TypeLabel = uilabel(app.LeftPanel);
            app.TypeLabel.Text = 'Type:';
            app.TypeSwitch = uiswitch(app.LeftPanel, 'slider');
            app.TypeSwitch.Items = {'Undamped', 'Damped'};
            app.TypeSwitch.ValueChangedFcn = createCallbackFcn(app, @TypeSwitchValueChanged, true);
            app.TypeSwitch.Value = 'Undamped';
            app.EditFieldLabel = uilabel(app.LeftPanel);
            app.EditFieldLabel.Text = 'ω0';
            app.FrequencyText = uieditfield(app.LeftPanel, 'numeric');
            app.FrequencyText.ValueChangedFcn = createCallbackFcn(app, @FrequencyTextValueChanged, true);
            app.FrequencyText.Value = 1;
            app.AmplitudeSpinnerLabel = uilabel(app.LeftPanel);
            app.AmplitudeSpinnerLabel.Text = 'Amplitude';
            app.AmplitudeSpinner = uispinner(app.LeftPanel);
            app.AmplitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSpinnerValueChanged, true);
            app.DampingSwitchLabel = uilabel(app.LeftPanel);
            app.DampingSwitchLabel.Visible = 'off';
            app.DampingSwitchLabel.Text = 'Damping';
            app.DampingSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.DampingSwitch.Items = {'Low', 'High'};
            app.DampingSwitch.ValueChangedFcn = createCallbackFcn(app, @DampingSwitchValueChanged, true);
            app.DampingSwitch.Visible = 'off';
            app.DampingSwitch.Value = 'Low';
            app.InitialPhaseLabel = uilabel(app.LeftPanel);
            app.InitialPhaseLabel.Text = 'Initial Phase (ϕ)';
            app.InitialPhaseKnob = uiknob(app.LeftPanel, 'continuous');
            app.InitialPhaseKnob.Limits = [0 6.28318530717959];
            app.InitialPhaseKnob.MajorTicks = [0 1.5707963267949 3.14159265358979 4.71238898038469 6.28318530717959];
            app.InitialPhaseKnob.MajorTickLabels = {'0', 'π/2', 'π', '3π/2', '2π'};
            app.InitialPhaseKnob.ValueChangedFcn = createCallbackFcn(app, @InitialPhaseKnobValueChanged, true);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Visible = 'off';
            app.Label.Text = 'γ';
            app.GammaText = uislider(app.LeftPanel);
            app.GammaText.Limits = [0 4];
            app.GammaText.ValueChangedFcn = createCallbackFcn(app, @GammaTextValueChanged, true);
            app.GammaText.Visible = 'off';
            app.TypeSwitch_2Label = uilabel(app.LeftPanel);
            app.TypeSwitch_2Label.Visible = 'off';
            app.TypeSwitch_2Label.Text = 'Type:';
            app.TypeSwitch_2 = uiswitch(app.LeftPanel, 'slider');
            app.TypeSwitch_2.Items = {'Exponential', 'Logistic'};
            app.TypeSwitch_2.ValueChangedFcn = createCallbackFcn(app, @TypeSwitch_2ValueChanged, true);
            app.TypeSwitch_2.Visible = 'off';
            app.TypeSwitch_2.Value = 'Exponential';
            app.PoSpinnerLabel = uilabel(app.LeftPanel);
            app.PoSpinnerLabel.Visible = 'off';
            app.PoSpinnerLabel.Text = 'Po';
            app.PopulationSpinner = uispinner(app.LeftPanel);
            app.PopulationSpinner.ValueChangedFcn = createCallbackFcn(app, @PopulationSpinnerValueChanged, true);
            app.PopulationSpinner.Visible = 'off';
            app.InputMethodSwitchLabel = uilabel(app.LeftPanel);
            app.InputMethodSwitchLabel.Visible = 'off';
            app.InputMethodSwitchLabel.Text = 'Input Method';
            app.InputMethodSwitch = uiswitch(app.LeftPanel, 'rocker');
            app.InputMethodSwitch.Items = {'Constants', 'Rates'};
            app.InputMethodSwitch.ValueChangedFcn = createCallbackFcn(app, @InputMethodSwitchValueChanged, true);
            app.InputMethodSwitch.Visible = 'off';
            app.InputMethodSwitch.Value = 'Constants';
            app.KEditFieldLabel = uilabel(app.LeftPanel);
            app.KEditFieldLabel.Visible = 'off';
            app.KEditFieldLabel.Text = 'K:';
            app.KEditField = uieditfield(app.LeftPanel, 'numeric');
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @KEditFieldValueChanged, true);
            app.KEditField.Visible = 'off';
            app.MEditFieldLabel = uilabel(app.LeftPanel);
            app.MEditFieldLabel.Visible = 'off';
            app.MEditFieldLabel.Text = 'M:';
            app.MEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.MEditField.Visible = 'off';
            app.InitialBirthRate0EditFieldLabel = uilabel(app.LeftPanel);
            app.InitialBirthRate0EditFieldLabel.Visible = 'off';
            app.InitialBirthRate0EditFieldLabel.Text = 'Initial Birth Rate (β0)';
            app.InitialBirthRate0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.InitialBirthRate0EditField.ValueChangedFcn = createCallbackFcn(app, @InitialBirthRate0EditFieldValueChanged, true);
            app.InitialBirthRate0EditField.Visible = 'off';
            app.BirthRate1EditFieldLabel = uilabel(app.LeftPanel);
            app.BirthRate1EditFieldLabel.Visible = 'off';
            app.BirthRate1EditFieldLabel.Text = 'Birth Rate (β1)';
            app.BirthRate1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.BirthRate1EditField.ValueChangedFcn = createCallbackFcn(app, @BirthRate1EditFieldValueChanged, true);
            app.BirthRate1EditField.Visible = 'off';
            app.DeathRate0EditFieldLabel = uilabel(app.LeftPanel);
            app.DeathRate0EditFieldLabel.Visible = 'off';
            app.DeathRate0EditFieldLabel.Text = 'Death Rate (𝛿0)';
            app.DeathRate0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.DeathRate0EditField.ValueChangedFcn = createCallbackFcn(app, @DeathRate0EditFieldValueChanged, true);
            app.DeathRate0EditField.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ODE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end